classdef robotMonitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Axes_J2_angle    matlab.ui.control.UIAxes
        Axes_J3_angle    matlab.ui.control.UIAxes
        Axes_J2_PWM      matlab.ui.control.UIAxes
        Axes_J3_PWM      matlab.ui.control.UIAxes
        Axes_VSL1        matlab.ui.control.UIAxes
        Axes_VSL2        matlab.ui.control.UIAxes
        Axes_X_pos       matlab.ui.control.UIAxes
        Axes_Y_pos       matlab.ui.control.UIAxes
        Axes_Z_pos       matlab.ui.control.UIAxes
        RecordingButton  matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        % Joint 2
        J2_present_angle;
        J2_array_angle;
        J2_present_PWM;
        J2_array_PWM;
        
        % Joint 3
        J3_present_angle;
        J3_array_angle;
        J3_present_PWM;
        J3_array_PWM;
        
        % Pressure
        VSL1_present_pressure;
        VSL1_array_pressure;
        VSL2_present_pressure; 
        VSL2_array_pressure;  
        
        % Instance to the robotControl_app class
        robotControl_app;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.robotControl_app = evalin('base','robotControl_app');
            
            % initialization
            app.J2_array_angle = [];
            app.J2_array_PWM = [];
            
            app.J3_array_angle = [];
            app.J3_array_PWM = [];
            
            app.VSL1_array_pressure = [];
            app.VSL2_array_pressure = [];
            
        end

        % Value changed function: RecordingButton
        function RecordingButtonValueChanged(app, event)
            cont = 0;
            while app.RecordingButton.Value
                app.J2_present_angle = read2ByteTxRx(app.robotControl_app.dxl_port_num, app.robotControl_app.PROTOCOL_VERSION, app.robotControl_app.DXL1_ID, app.robotControl_app.ADD_PRESENT_POSITION);
                app.J2_array_angle = [app.J2_array_angle app.J2_present_angle];
                
                app.J3_present_angle = read2ByteTxRx(app.robotControl_app.dxl_port_num, app.robotControl_app.PROTOCOL_VERSION, app.robotControl_app.DXL2_ID, app.robotControl_app.ADD_PRESENT_POSITION);
                app.J3_array_angle = [app.J3_array_angle app.J3_present_angle];

                app.J2_present_PWM = read2ByteTxRx(app.robotControl_app.dxl_port_num, app.robotControl_app.PROTOCOL_VERSION, app.robotControl_app.DXL1_ID, app.robotControl_app.ADD_PRESENT_PWM);
                if app.J2_present_PWM < 1000
                    app.J2_array_PWM = [app.J2_array_PWM app.J2_present_PWM];
                end
                
                app.J3_present_PWM = read2ByteTxRx(app.robotControl_app.dxl_port_num, app.robotControl_app.PROTOCOL_VERSION, app.robotControl_app.DXL2_ID, app.robotControl_app.ADD_PRESENT_PWM);
                if app.J3_present_PWM < 1000
                    app.J3_array_PWM = [app.J3_array_PWM app.J3_present_PWM];
                end
                
                cont = cont + 1;
                if cont == 5
                    plot(app.Axes_J2_angle, app.J2_array_angle); 
                    plot(app.Axes_J3_angle, app.J3_array_angle);
                    plot(app.Axes_J2_PWM, app.J2_array_PWM); 
                    plot(app.Axes_J3_PWM, app.J3_array_PWM); 
                    cont = 0;
                end                                
                pause(0.1);

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Axes_J2_angle = uiaxes(app.UIFigure);
            title(app.Axes_J2_angle, 'J2 angle')
            xlabel(app.Axes_J2_angle, 'time [s]')
            ylabel(app.Axes_J2_angle, 'angle [º]')
            app.Axes_J3_angle = uiaxes(app.UIFigure);
            title(app.Axes_J3_angle, 'J3 angle')
            xlabel(app.Axes_J3_angle, 'X')
            ylabel(app.Axes_J3_angle, 'angle [º]')
            app.Axes_J2_PWM = uiaxes(app.UIFigure);
            title(app.Axes_J2_PWM, 'J2 PWM')
            xlabel(app.Axes_J2_PWM, 'time [s]')
            ylabel(app.Axes_J2_PWM, 'PWM [%]')
            app.Axes_J3_PWM = uiaxes(app.UIFigure);
            title(app.Axes_J3_PWM, 'J3 PWM')
            xlabel(app.Axes_J3_PWM, 'X')
            ylabel(app.Axes_J3_PWM, 'Y')
            app.Axes_VSL1 = uiaxes(app.UIFigure);
            title(app.Axes_VSL1, 'Link2 Pressure')
            xlabel(app.Axes_VSL1, 'time [s]')
            ylabel(app.Axes_VSL1, 'Pressure [bar]')
            app.Axes_VSL2 = uiaxes(app.UIFigure);
            title(app.Axes_VSL2, 'Link3 Pressure')
            xlabel(app.Axes_VSL2, 'X')
            ylabel(app.Axes_VSL2, 'Y')
            app.Axes_X_pos = uiaxes(app.UIFigure);
            title(app.Axes_X_pos, 'X')
            xlabel(app.Axes_X_pos, 'X')
            ylabel(app.Axes_X_pos, 'Y')
            app.Axes_Y_pos = uiaxes(app.UIFigure);
            title(app.Axes_Y_pos, 'Y')
            xlabel(app.Axes_Y_pos, 'X')
            ylabel(app.Axes_Y_pos, 'Y')
            app.Axes_Z_pos = uiaxes(app.UIFigure);
            title(app.Axes_Z_pos, 'Z')
            xlabel(app.Axes_Z_pos, 'X')
            ylabel(app.Axes_Z_pos, 'Y')
            app.RecordingButton = uibutton(app.UIFigure, 'state');
            app.RecordingButton.ValueChangedFcn = createCallbackFcn(app, @RecordingButtonValueChanged, true);
            app.RecordingButton.Text = 'Recording';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robotMonitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end